classdef HomingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HomingFigure                matlab.ui.Figure
        NetForceNEditField          matlab.ui.control.NumericEditField
        NetForceNEditFieldLabel     matlab.ui.control.Label
        LoadCellBotNEditField       matlab.ui.control.NumericEditField
        LoadCellBotNEditFieldLabel  matlab.ui.control.Label
        LoadCellTopNEditField       matlab.ui.control.NumericEditField
        LoadCellTopNEditFieldLabel  matlab.ui.control.Label
        DrawWirePositionmEditField  matlab.ui.control.NumericEditField
        DrawWirePositionmEditFieldLabel  matlab.ui.control.Label
        MotorPositionmEditField     matlab.ui.control.NumericEditField
        MotorPositionmLabel         matlab.ui.control.Label
        HomingOverviewPanel         matlab.ui.container.Panel
        ModeofoperationdisplayEditField  matlab.ui.control.NumericEditField
        ModeofoperationdisplayEditFieldLabel  matlab.ui.control.Label
        HWRLShareSwitch             matlab.ui.control.Switch
        HWRLShareSwitchLabel        matlab.ui.control.Label
        ExperimentEditField         matlab.ui.control.EditField
        ExperimentEditFieldLabel    matlab.ui.control.Label
        ProjectEditField            matlab.ui.control.EditField
        ProjectEditFieldLabel       matlab.ui.control.Label
        TrialSpinner                matlab.ui.control.Spinner
        TrialSpinnerLabel           matlab.ui.control.Label
        TimesEditField              matlab.ui.control.NumericEditField
        TimesEditFieldLabel         matlab.ui.control.Label
        ClearFaultsButton           matlab.ui.control.Button
        PositionmSpinner            matlab.ui.control.Spinner
        PositionmSpinnerLabel       matlab.ui.control.Label
        FaultLamp                   matlab.ui.control.Lamp
        FaultLampLabel              matlab.ui.control.Label
        EnableDriveButton           matlab.ui.control.Button
        StartStopButton             matlab.ui.control.Button
        eCATStateEditField          matlab.ui.control.NumericEditField
        eCATStateEditFieldLabel     matlab.ui.control.Label
        ModelNameLabel              matlab.ui.control.Label
        PosCountsAxes               matlab.ui.control.UIAxes
        NetForceAxes                matlab.ui.control.UIAxes
        LoadCellAxes                matlab.ui.control.UIAxes
        PositionAxes                matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        mdlName
        buildDir
        tg
        tgName
        hInst
        startIcon = fullfile('icons','run_24.png')
        stopIcon = fullfile('icons','stop_24.png')
    end

    methods (Access = private)

        function setupInstrumentation(app)
            app.tg.removeAllInstruments;
            app.hInst = slrealtime.Instrument(app.mdlName);

            app.hInst.connectScalar(app.eCATStateEditField,[app.mdlName,'/Initialization/eCATstate'],1,'Decimation',1000)
            app.hInst.connectScalar(app.FaultLamp,[app.mdlName,'/Initialization/Fault'],1,'PropertyName','Color','Callback',@app.STOlamp)
            app.hInst.connectScalar(app.TimesEditField,[app.mdlName,'/Initialization/Clock'],1,'Decimation',100)
            % app.hInst.connectScalar(app.EStopLamp,[app.mdlName,'/LUPAInputs/ELMOInputs/STOinterlock'],1,'Decimation',app.decimation,'PropertyName','Color','Callback',@app.STOlamp);

            app.hInst.connectScalar(app.MotorPositionmEditField,[app.mdlName,'/motorPos_m'],1,'Decimation',1000)
            app.hInst.connectScalar(app.DrawWirePositionmEditField,[app.mdlName,'/DrawWire/drawWirePos_m'],1,'Decimation',1000)
            app.hInst.connectScalar(app.LoadCellBotNEditField,[app.mdlName,'/LoadCells/LCBOT'],1,'Decimation',1000)
            app.hInst.connectScalar(app.LoadCellTopNEditField,[app.mdlName,'/LoadCells/LCTOP'],1,'Decimation',1000)
            app.hInst.connectScalar(app.NetForceNEditField,[app.mdlName,'/LinearMotion/netForce_N'],1,'Decimation',1000)
            app.hInst.connectScalar(app.ModeofoperationdisplayEditField,[app.mdlName,'/Initialization/modeOfOperationDisplay'],1,'Decimation',1000)


            app.hInst.connectLine(app.PositionAxes,[app.mdlName,'/motorPos_m'],1,'Decimation',100)
            app.hInst.connectLine(app.PositionAxes,[app.mdlName,'/DrawWire/drawWirePos_m'],1,'Decimation',100)
            legend(app.PositionAxes,'Motor Position (m)','Draw Wire Position (m)')
            ylim(app.PositionAxes,[-0.3,0.3])

            app.hInst.connectLine(app.PosCountsAxes,[app.mdlName,'/targetPos_counts'],1,'Decimation',100)
            app.hInst.connectLine(app.PosCountsAxes,[app.mdlName,'/actPos_counts'],1,'Decimation',100)
            legend(app.PosCountsAxes,'target','actual')

            app.hInst.connectLine(app.LoadCellAxes,[app.mdlName,'/LoadCells/LCTOP'],1,'Decimation',100)
            app.hInst.connectLine(app.LoadCellAxes,[app.mdlName,'/LoadCells/LCBOT'],1,'Decimation',100)
            legend(app.LoadCellAxes,'Top','Bottom')

            app.hInst.connectLine(app.NetForceAxes,[app.mdlName,'/LinearMotion/netForce_N'],1,'Decimation',100)

            app.tg.addInstrument(app.hInst)
        end
        function color = STOlamp(~,~,d)
            if d
                color = [0,1,0];
            else
                color = [1,0,0];
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.HomingFigure.Name = 'LUPA Homing';
            drawnow;
            app.HomingFigure.WindowState = 'maximized';

            app.mdlName = evalin('base','mdlName');
            modelVersion = evalin('base','mdlVersion');
            app.ModelNameLabel.Text = ['Model: ',app.mdlName,' Version ', modelVersion];

            app.StartStopButton.Icon = app.startIcon;
            app.StartStopButton.Text = 'Start Homing';

            app.EnableDriveButton.Text = 'Enable Drive';
            app.EnableDriveButton.BackgroundColor = 'Red';

            app.tgName = evalin('base','tgName');
            app.tg = slrealtime(app.tgName);

            app.tg.stop
            app.tg.removeAllInstruments();

            app.buildDir = evalin('base','buildDir');
            app.tg.load(fullfile(app.buildDir,app.mdlName))

            app.ClearFaultsButton.Text = 'Clear Faults';

            app.HWRLShareSwitch.Value = 'No';

            app.setupInstrumentation;

        end

        % Button pushed function: StartStopButton
        function StartStopButtonPushed(app, event)
            if strcmp(app.StartStopButton.Icon,app.startIcon)
                app.setupInstrumentation;
                app.tg.start('ReloadOnStop',true,'AutoImportFileLog',false);
                app.StartStopButton.Icon = app.stopIcon;
                app.StartStopButton.Text = 'Stop';
            else
                app.tg.stop;
                app.StartStopButton.Icon = app.startIcon;
                app.StartStopButton.Text = 'Start';
                run('postProcess.m');
            end
        end

        % Button pushed function: EnableDriveButton
        function EnableDriveButtonPushed(app, event)
            if strcmp(app.EnableDriveButton.Text,'Enable Drive')
                app.tg.setparam([app.mdlName,'/Initialization/enableDrive'],'Value',1);
                app.EnableDriveButton.Text = 'Drive Enabled';
                app.EnableDriveButton.BackgroundColor = 'Green';

            else
                app.tg.setparam([app.mdlName,'/Initialization/enableDrive'],'Value',0);
                app.EnableDriveButton.Text = 'Enable Drive';
                app.EnableDriveButton.BackgroundColor = 'Red';
            end
        end

        % Value changed function: PositionmSpinner
        function PositionmSpinnerValueChanged(app, event)
            value = app.PositionmSpinner.Value;
            app.tg.setparam([app.mdlName,'/CommandPosition_m'],'Value',value)
        end

        % Button pushed function: ClearFaultsButton
        function ClearFaultsButtonPushed(app, event)
            if strcmp(app.ClearFaultsButton.Text,'Clear Faults')
                app.tg.setparam([app.mdlName,'/Initialization/clearFaults'],'Value',1);
                app.ClearFaultsButton.Text = 'Faults Clearing';
                pause(1);
                app.tg.setparam([app.mdlName,'/Initialization/clearFaults'],'Value',0);
                app.ClearFaultsButton.Text = 'Clear Faults';
            end            
        end

        % Value changed function: HWRLShareSwitch
        function HWRLShareSwitchValueChanged(app, event)
            value = app.HWRLShareSwitch.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HomingFigure = uifigure('Visible', 'off');
            app.HomingFigure.Name = 'MATLAB App';
            app.PositionAxes = uiaxes(app.HomingFigure);
            title(app.PositionAxes, 'Position')
            app.LoadCellAxes = uiaxes(app.HomingFigure);
            title(app.LoadCellAxes, 'Load Cells')
            ylabel(app.LoadCellAxes, 'F (N)')
            app.NetForceAxes = uiaxes(app.HomingFigure);
            title(app.NetForceAxes, 'Net Force')
            ylabel(app.NetForceAxes, 'F (N)')
            app.PosCountsAxes = uiaxes(app.HomingFigure);
            title(app.PosCountsAxes, 'Position Counts')
            xlabel(app.PosCountsAxes, 'time(s)')
            ylabel(app.PosCountsAxes, 'pos (counts)')
            zlabel(app.PosCountsAxes, 'Z')
            app.HomingOverviewPanel = uipanel(app.HomingFigure);
            app.HomingOverviewPanel.Title = 'Homing Overview';
            app.ModelNameLabel = uilabel(app.HomingOverviewPanel);
            app.ModelNameLabel.Text = 'ModelName';
            app.eCATStateEditFieldLabel = uilabel(app.HomingOverviewPanel);
            app.eCATStateEditFieldLabel.Text = 'eCATState';
            app.eCATStateEditField = uieditfield(app.HomingOverviewPanel, 'numeric');
            app.StartStopButton = uibutton(app.HomingOverviewPanel, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'Start Stop Button';
            app.EnableDriveButton = uibutton(app.HomingOverviewPanel, 'push');
            app.EnableDriveButton.ButtonPushedFcn = createCallbackFcn(app, @EnableDriveButtonPushed, true);
            app.EnableDriveButton.Text = 'Enable Drive';
            app.FaultLampLabel = uilabel(app.HomingOverviewPanel);
            app.FaultLampLabel.Text = 'Fault';
            app.FaultLamp = uilamp(app.HomingOverviewPanel);
            app.PositionmSpinnerLabel = uilabel(app.HomingOverviewPanel);
            app.PositionmSpinnerLabel.Text = 'Position(m)';
            app.PositionmSpinner = uispinner(app.HomingOverviewPanel);
            app.PositionmSpinner.Step = 0.01;
            app.PositionmSpinner.Limits = [-0.25 0.25];
            app.PositionmSpinner.ValueChangedFcn = createCallbackFcn(app, @PositionmSpinnerValueChanged, true);
            app.ClearFaultsButton = uibutton(app.HomingOverviewPanel, 'push');
            app.ClearFaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFaultsButtonPushed, true);
            app.ClearFaultsButton.Text = 'Clear Faults';
            app.TimesEditFieldLabel = uilabel(app.HomingOverviewPanel);
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimesEditField = uieditfield(app.HomingOverviewPanel, 'numeric');
            app.TrialSpinnerLabel = uilabel(app.HomingOverviewPanel);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.HomingOverviewPanel);
            app.TrialSpinner.Limits = [1 Inf];
            app.TrialSpinner.Value = 1;
            app.ProjectEditFieldLabel = uilabel(app.HomingOverviewPanel);
            app.ProjectEditFieldLabel.Text = 'Project';
            app.ProjectEditField = uieditfield(app.HomingOverviewPanel, 'text');
            app.ProjectEditField.Value = 'mCDRLUPA8';
            app.ExperimentEditFieldLabel = uilabel(app.HomingOverviewPanel);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.HomingOverviewPanel, 'text');
            app.ExperimentEditField.Value = 'Homing';
            app.HWRLShareSwitchLabel = uilabel(app.HomingOverviewPanel);
            app.HWRLShareSwitchLabel.Text = 'HWRL Share';
            app.HWRLShareSwitch = uiswitch(app.HomingOverviewPanel, 'slider');
            app.HWRLShareSwitch.Items = {'No', 'Yes'};
            app.HWRLShareSwitch.ValueChangedFcn = createCallbackFcn(app, @HWRLShareSwitchValueChanged, true);
            app.HWRLShareSwitch.Value = 'Yes';
            app.ModeofoperationdisplayEditFieldLabel = uilabel(app.HomingOverviewPanel);
            app.ModeofoperationdisplayEditFieldLabel.Text = 'Mode of operation display';
            app.ModeofoperationdisplayEditField = uieditfield(app.HomingOverviewPanel, 'numeric');
            app.MotorPositionmLabel = uilabel(app.HomingFigure);
            app.MotorPositionmLabel.Text = 'Motor Position (m)';
            app.MotorPositionmEditField = uieditfield(app.HomingFigure, 'numeric');
            app.DrawWirePositionmEditFieldLabel = uilabel(app.HomingFigure);
            app.DrawWirePositionmEditFieldLabel.Text = 'Draw Wire Position (m)';
            app.DrawWirePositionmEditField = uieditfield(app.HomingFigure, 'numeric');
            app.LoadCellTopNEditFieldLabel = uilabel(app.HomingFigure);
            app.LoadCellTopNEditFieldLabel.Text = 'Load Cell Top (N)';
            app.LoadCellTopNEditField = uieditfield(app.HomingFigure, 'numeric');
            app.LoadCellBotNEditFieldLabel = uilabel(app.HomingFigure);
            app.LoadCellBotNEditFieldLabel.Text = 'Load Cell Bot (N)';
            app.LoadCellBotNEditField = uieditfield(app.HomingFigure, 'numeric');
            app.NetForceNEditFieldLabel = uilabel(app.HomingFigure);
            app.NetForceNEditFieldLabel.Text = 'Net Force (N)';
            app.NetForceNEditField = uieditfield(app.HomingFigure, 'numeric');
            % Show the figure after all components are created
            app.HomingFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HomingApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Associate the Simulink Model
                app.Simulation = simulation('Homing');

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.HomingFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.HomingFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HomingFigure)
        end
    end
end