classdef LUPAapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LUPAUIFigure                 matlab.ui.Figure
        CustomFeedbackControlPanel   matlab.ui.container.Panel
        EnableCustomFeedbackControlButton  matlab.ui.control.Button
        AgentDropDown                matlab.ui.control.DropDown
        AgentDropDownLabel           matlab.ui.control.Label
        ScopesPanel                  matlab.ui.container.Panel
        Damping                      matlab.ui.control.UIAxes
        Stiffness                    matlab.ui.control.UIAxes
        position                     matlab.ui.control.UIAxes
        LoadCell                     matlab.ui.control.UIAxes
        Power                        matlab.ui.control.UIAxes
        Torque                       matlab.ui.control.UIAxes
        Velocity                     matlab.ui.control.UIAxes
        LinearMotionFeedbackPanel    matlab.ui.container.Panel
        NetForceNGauge               matlab.ui.control.LinearGauge
        NetForceNGaugeLabel          matlab.ui.control.Label
        RelativeBodyPositionmGauge   matlab.ui.control.LinearGauge
        RelativePositionLabel        matlab.ui.control.Label
        MotorFeedbackPanel           matlab.ui.container.Panel
        CountsEditField              matlab.ui.control.NumericEditField
        CountsEditFieldLabel         matlab.ui.control.Label
        MotorPositionradGauge        matlab.ui.control.Gauge
        MotorPositionradGaugeLabel   matlab.ui.control.Label
        MotorPowerWGauge             matlab.ui.control.LinearGauge
        MotorPowerWGaugeLabel        matlab.ui.control.Label
        Current                      matlab.ui.control.UIAxes
        FeedbackControlPanel         matlab.ui.container.Panel
        EnableFeedbackControlButton  matlab.ui.control.Button
        StiffnessSpinner             matlab.ui.control.Spinner
        StiffnessSpinnerLabel        matlab.ui.control.Label
        DampingSpinner               matlab.ui.control.Spinner
        DampingSpinnerLabel          matlab.ui.control.Label
        SourceDropDown               matlab.ui.control.DropDown
        SourceDropDownLabel          matlab.ui.control.Label
        ReferenceSignalPanel         matlab.ui.container.Panel
        SinePeriodEditField          matlab.ui.control.NumericEditField
        SinePeriodEditFieldLabel     matlab.ui.control.Label
        AmplitudeSpinner             matlab.ui.control.Spinner
        AmplitudeSpinnerLabel        matlab.ui.control.Label
        SignalDropDown               matlab.ui.control.DropDown
        SignalDropDownLabel          matlab.ui.control.Label
        AppCtrlPanel                 matlab.ui.container.Panel
        HWRLShareSwitch              matlab.ui.control.Switch
        HWRLShareSwitchLabel         matlab.ui.control.Label
        EStopLamp                    matlab.ui.control.Lamp
        EStopLampLabel               matlab.ui.control.Label
        FaultLamp                    matlab.ui.control.Lamp
        FaultLampLabel               matlab.ui.control.Label
        CurrentLimitSpinner          matlab.ui.control.Spinner
        CurrentLimitSpinnerLabel     matlab.ui.control.Label
        TrialSpinner                 matlab.ui.control.Spinner
        TrialSpinnerLabel            matlab.ui.control.Label
        ExperimentEditField          matlab.ui.control.EditField
        ExperimentEditFieldLabel     matlab.ui.control.Label
        ProjectEditField             matlab.ui.control.EditField
        ProjectEditFieldLabel        matlab.ui.control.Label
        ClearFaultsButton            matlab.ui.control.Button
        EnableDriveButton            matlab.ui.control.Button
        StartStopButton              matlab.ui.control.Button
        ApplicationOverviewPanel     matlab.ui.container.Panel
        ModeEditField                matlab.ui.control.EditField
        ModeLabel                    matlab.ui.control.Label
        TsEditField                  matlab.ui.control.NumericEditField
        TsEditFieldLabel             matlab.ui.control.Label
        SprocketEditField            matlab.ui.control.NumericEditField
        SprocketEditFieldLabel       matlab.ui.control.Label
        DriveTempCEditField          matlab.ui.control.NumericEditField
        DriveTempCEditFieldLabel     matlab.ui.control.Label
        MotorTempCEditField          matlab.ui.control.NumericEditField
        MotorTempCEditFieldLabel     matlab.ui.control.Label
        eCATStateEditField           matlab.ui.control.NumericEditField
        eCATStateEditFieldLabel      matlab.ui.control.Label
        TETsEditField                matlab.ui.control.NumericEditField
        TETsEditFieldLabel           matlab.ui.control.Label
        TimesEditField               matlab.ui.control.NumericEditField
        TimesEditFieldLabel          matlab.ui.control.Label
        ModelNameLabel               matlab.ui.control.Label
    end


    properties (Access = private)
        buildDir
        mdlName
        tg  % the realtime target
        tgName % realtime target name
        Ts
        Mode
        sprocketTeeth
        period
        hInst   % handle to the instrument object
        decimation
        decimationAxes  % graphics update rate (relative to base rate)
        startIcon = fullfile('icons','run_24.png')
        stopIcon = fullfile('icons','stop_24.png')

    end

    methods (Access = private)

        function setupInstrumentation(app)
            app.tg.removeAllInstruments;
            app.hInst = slrealtime.Instrument(app.mdlName);  % create instrument object

            % Application Overview
            app.hInst.connectScalar(app.TimesEditField,[app.mdlName,'/Initialization/Clock'],1,'Decimation',app.decimation)
            app.hInst.connectScalar(app.TETsEditField,[app.mdlName,'/Initialization/tet'],1,'Decimation',app.decimation)
            app.hInst.connectScalar(app.eCATStateEditField,[app.mdlName,'/Initialization/eCATstate'],1,'Decimation',app.decimation)
            app.hInst.connectScalar(app.DriveTempCEditField,[app.mdlName,'/LUPAInputs/ELMOInputs/driveTemp_degC'],1)
            app.hInst.connectScalar(app.MotorTempCEditField,[app.mdlName,'/LUPAInputs/temp_degC'],1,'Decimation',app.decimation)
            app.hInst.connectScalar(app.CountsEditField,[app.mdlName,'/LUPAInputs/ELMOInputs/pos_cnts'],1,'Decimation',app.decimation)

            % App Control
            app.hInst.connectScalar(app.FaultLamp,[app.mdlName,'/Initialization/Fault'],1,'Decimation',app.decimation,'PropertyName','Color','Callback',@app.STOlamp);
            app.hInst.connectScalar(app.EStopLamp,[app.mdlName,'/LUPAInputs/ELMOInputs/STOinterlock'],1,'Decimation',app.decimation,'PropertyName','Color','Callback',@app.STOlamp);

            % Motor Feedback

            app.hInst.connectScalar(app.MotorPowerWGauge,[app.mdlName,'/LUPAInputs/ELMOInputs/pow_W'],1,'Decimation',app.decimation)
            app.hInst.connectScalar(app.MotorPositionradGauge,[app.mdlName,'/LUPAInputs/ELMOInputs/pos_rad2'],1,'Decimation',app.decimation)

            % Belt Feedback
            app.hInst.connectScalar(app.NetForceNGauge,[app.mdlName,'/LUPAInputs/netForce_N'],1,'Decimation',app.decimation)
            app.hInst.connectScalar(app.RelativeBodyPositionmGauge,[app.mdlName,'/LUPAInputs/drawWire_m2'],1,'Decimation',app.decimation)

            % Scopes
            app.hInst.connectLine(app.Current,[app.mdlName,'/Outputs/Target_A'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.Current,[app.mdlName,'/LUPAInputs/ELMOInputs/current_A'],1,'Decimation',app.decimationAxes)

            app.hInst.connectLine(app.Velocity,[app.mdlName,'/LUPAInputs/ELMOInputs/vel_radpers'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.Velocity,[app.mdlName,'/vel_filt_radpers'],1,'Decimation',app.decimationAxes)

            app.hInst.connectLine(app.Torque,[app.mdlName,'/LUPAInputs/ELMOInputs/torque_Nm'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.position,[app.mdlName,'/LUPAInputs/ELMOInputs/pos_rad2'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.position,[app.mdlName,'/pos_filt_rad'],1,'Decimation',app.decimationAxes)

            app.hInst.connectLine(app.Damping,[app.mdlName,'/Outputs/damping_Npermpers'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.Stiffness,[app.mdlName,'/Outputs/stiffness_Nperm'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.Damping,[app.mdlName,'/Outputs/damping_Cust_Npermpers'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.Stiffness,[app.mdlName,'/Outputs/stiffness_Cust_Nperm'],1,'Decimation',app.decimationAxes)

            app.hInst.connectLine(app.Torque,[app.mdlName,'/Outputs/Target_Nm'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.Power,[app.mdlName,'/LUPAInputs/ELMOInputs/pow_W'],1,'Decimation',app.decimationAxes)
            %             app.hInst.connectLine(app.Power,[app.mdlName,'/LUPAInputs/linearPower'],1,'Decimation',app.decimationAxes)
            app.hInst.connectLine(app.LoadCell,[app.mdlName,'/LUPAInputs/LoadCells/LCTOP'],1,'Decimation',app.decimationAxes)
            % app.hInst.connectLine(app.LoadCell,[app.mdlName,'/LUPAInputs/LCBOT'],1,'Decimation',app.decimationAxes)
            % app.hInst.connectLine(app.UIAxes_6,[app.mdlName,'/LUPAInputs/preTension_N'],1,'Decimation',app.decimationAxes)

            legend(app.Velocity,'raw','filtered');
            legend(app.Torque,'ELMO Reported','Commanded');
            legend(app.position,'raw','filtered');
            legend(app.Current,'Commanded Current','Actual Current')
            % legend(app.LoadCell,'Top Load Cell','Bottom Load Cell')

            % add the instrument object to the app
            app.tg.addInstrument(app.hInst)

        end
        function color = STOlamp(~,~,d)
            if d
                color = [0,1,0];
            else
                color = [1,0,0];
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LUPAUIFigure.Name = 'LUPA';
            drawnow;
            app.LUPAUIFigure.WindowState = 'maximized';

            app.mdlName = evalin('base','mdlName');
            modelVersion = evalin('base','mdlVersion');
            app.ModelNameLabel.Text = ['Model: ',app.mdlName,' Version ', modelVersion];

            app.decimation = evalin('base','appDecimation');
            app.decimationAxes = evalin('base','appDecimationAxes');

            app.Mode = evalin('base','Mode');
            app.ModeEditField.Value = app.Mode;

            app.sprocketTeeth = evalin('base','sprocketTeeth');
            app.SprocketEditField.Value = app.sprocketTeeth;

            app.period = evalin('base','period');
            app.SinePeriodEditField.Value = app.period;

            app.Ts = evalin('base','Ts');
            app.TsEditField.Value = app.Ts;

            app.tgName = evalin('base','tgName');
            app.tg = slrealtime(app.tgName);

            app.tg.stop;
            app.tg.removeAllInstruments();

            app.buildDir = evalin('base','buildDir');
            app.tg.load(fullfile(app.buildDir,app.mdlName));

            app.StartStopButton.Icon = app.startIcon;
            app.StartStopButton.Text = 'Start LUPA';

            app.EnableDriveButton.Text = 'Enable Drive';
            app.EnableDriveButton.BackgroundColor = 'Red';

            app.EnableFeedbackControlButton.Text = 'Enable Ctrl';
            app.EnableFeedbackControlButton.BackgroundColor = 'Red';

            app.EnableCustomFeedbackControlButton.Text = 'Enable Custom Ctrl';
            app.EnableCustomFeedbackControlButton.BackgroundColor = 'Red';

            app.ClearFaultsButton.Text = 'Clear Faults';

            fnames = dir('RLdata\*.mat');
            app.AgentDropDown.Items = {'None',fnames.name};

            app.setupInstrumentation;
        end

        % Value changed function: CurrentLimitSpinner
        function CurrentLimitSpinnerValueChanged(app, event)
            value = app.CurrentLimitSpinner.Value;
         app.tg.setparam([app.mdlName,'/Outputs/currentLimit'],'Value',value)
        end

        % Button pushed function: StartStopButton
        function StartStopButtonPushed(app, event)
            if strcmp(app.StartStopButton.Icon,app.startIcon)
                app.setupInstrumentation;
                app.tg.start('ReloadOnStop',true,'AutoImportFileLog',false);
                app.StartStopButton.Icon = app.stopIcon;
                app.StartStopButton.Text = 'Stop';
            else
                app.tg.stop;
                app.StartStopButton.Icon = app.startIcon;
                app.StartStopButton.Text = 'Start';
                run('postProcess.m');
            end
        end

        % Button pushed function: EnableDriveButton
        function EnableDriveButtonPushed(app, event)
            if strcmp(app.EnableDriveButton.Text,'Enable Drive')
                app.tg.setparam([app.mdlName,'/Initialization/enableDrive'],'Value',1);
                app.EnableDriveButton.Text = 'Drive Enabled';
                app.EnableDriveButton.BackgroundColor = 'Green';

            else
                app.tg.setparam([app.mdlName,'/Initialization/enableDrive'],'Value',0);
                app.EnableDriveButton.Text = 'Enable Drive';
                app.EnableDriveButton.BackgroundColor = 'Red';
            end
        end

        % Button pushed function: ClearFaultsButton
        function ClearFaultsButtonPushed(app, event)
            if strcmp(app.ClearFaultsButton.Text,'Clear Faults')
                app.tg.setparam([app.mdlName,'/Initialization/clearFaults'],'Value',1);
                app.ClearFaultsButton.Text = 'Faults Clearing';
                pause(1);
                app.tg.setparam([app.mdlName,'/Initialization/clearFaults'],'Value',0);
                app.ClearFaultsButton.Text = 'Clear Faults';
            end
        end

        % Value changed function: SignalDropDown
        function SignalDropDownValueChanged(app, event)
            value = app.SignalDropDown.Value;
            if strcmp(value,'None')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',1)
            elseif strcmp(value,'Ramps')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',2)
            elseif strcmp(value,'Sine')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',3)
            elseif strcmp(value,'MS1')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',4)
            elseif strcmp(value,'MS2')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',5)
            elseif strcmp(value,'MS3')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',6)
            elseif strcmp(value,'Chirp')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',7)
            elseif strcmp(value,'BandLimitedWN')
                app.tg.setparam([app.mdlName,'/Reference/inputSwitch'],'Value',8)
            end
        end

        % Value changed function: AmplitudeSpinner
        function AmplitudeSpinnerValueChanged(app, event)
            value = app.AmplitudeSpinner.Value;
            app.tg.setparam([app.mdlName,'/Outputs/refAmplitude'],'Gain',value)
        end

        % Value changed function: SourceDropDown
        function SourceDropDownValueChanged(app, event)
            value = app.SourceDropDown.Value;
            if strcmp(value,'Manual')
                app.tg.setparam([app.mdlName,'/Control/ctrlType'],'Value',1)
            elseif strcmp(value,'Excel')
                app.tg.setparam([app.mdlName,'/Control/ctrlType'],'Value',2)
            end
        end

        % Value changed function: DampingSpinner
        function DampingSpinnerValueChanged(app, event)
            value = app.DampingSpinner.Value;
            app.tg.setparam([app.mdlName,'/Control/damping'],'Value',value)
        end

        % Value changed function: StiffnessSpinner
        function StiffnessSpinnerValueChanged(app, event)
            value = app.StiffnessSpinner.Value;
            app.tg.setparam([app.mdlName,'/Control/stiffness'],'Value',value)
        end

        % Button pushed function: EnableFeedbackControlButton
        function EnableFeedbackControlButtonPushed(app, event)
            if strcmp(app.EnableFeedbackControlButton.Text,'Enable Ctrl')
                app.tg.setparam([app.mdlName,'/Outputs/enableControl'],'Value',1);
                app.EnableFeedbackControlButton.Text = 'Ctrl Enabled';
                app.EnableFeedbackControlButton.BackgroundColor = 'Green';

            else
                app.tg.setparam([app.mdlName,'/Outputs/enableControl'],'Value',0);
                app.EnableFeedbackControlButton.Text = 'Enable Ctrl';
                app.EnableFeedbackControlButton.BackgroundColor = 'Red';
            end
        end

        % Value changed function: HWRLShareSwitch
        function HWRLShareSwitchValueChanged(app, event)
            value = app.HWRLShareSwitch.Value;

        end

        % Value changed function: AgentDropDown
        function AgentDropDownValueChanged(app, event)
            value = app.AgentDropDown.Value;
            copyfile(['RLData\',value(1:end-4),'\blockAgentData.mat'],'blockAgentData.mat');
            open(['RLdata\',value]);
            Simulink.BlockDiagram.refreshBlocks(app.mdlName);
            slbuild(app.mdlName);
            load(app.tg,app.mdlName);
            
        end

        % Button pushed function: EnableCustomFeedbackControlButton
        function EnableCustomFeedbackControlButtonPushed(app, event)
            if strcmp(app.EnableCustomFeedbackControlButton.Text,'Enable Custom Ctrl')
                app.tg.setparam([app.mdlName,'/Outputs/enableCustControl'],'Value',1);
                app.EnableCustomFeedbackControlButton.Text = 'Custom Ctrl Enabled';
                app.EnableCustomFeedbackControlButton.BackgroundColor = 'Green';

            else
                app.tg.setparam([app.mdlName,'/Outputs/enableCustControl'],'Value',0);
                app.EnableCustomFeedbackControlButton.Text = 'Enable Custom Ctrl';
                app.EnableCustomFeedbackControlButton.BackgroundColor = 'Red';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LUPAUIFigure = uifigure('Visible', 'off');
            app.LUPAUIFigure.Name = 'LUPA';
            app.ApplicationOverviewPanel = uipanel(app.LUPAUIFigure);
            app.ApplicationOverviewPanel.Title = 'Application Overview';
            app.ModelNameLabel = uilabel(app.ApplicationOverviewPanel);
            app.ModelNameLabel.Text = 'ModelName';
            app.TimesEditFieldLabel = uilabel(app.ApplicationOverviewPanel);
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimesEditField = uieditfield(app.ApplicationOverviewPanel, 'numeric');
            app.TimesEditField.Limits = [0 Inf];
            app.TETsEditFieldLabel = uilabel(app.ApplicationOverviewPanel);
            app.TETsEditFieldLabel.Text = 'TET (s)';
            app.TETsEditField = uieditfield(app.ApplicationOverviewPanel, 'numeric');
            app.TETsEditField.ValueDisplayFormat = '%e';
            app.eCATStateEditFieldLabel = uilabel(app.ApplicationOverviewPanel);
            app.eCATStateEditFieldLabel.Text = 'eCAT State';
            app.eCATStateEditField = uieditfield(app.ApplicationOverviewPanel, 'numeric');
            app.MotorTempCEditFieldLabel = uilabel(app.ApplicationOverviewPanel);
            app.MotorTempCEditFieldLabel.Text = 'Motor Temp (°C)';
            app.MotorTempCEditField = uieditfield(app.ApplicationOverviewPanel, 'numeric');
            app.DriveTempCEditFieldLabel = uilabel(app.ApplicationOverviewPanel);
            app.DriveTempCEditFieldLabel.Text = 'Drive Temp (°C)';
            app.DriveTempCEditField = uieditfield(app.ApplicationOverviewPanel, 'numeric');
            app.SprocketEditFieldLabel = uilabel(app.ApplicationOverviewPanel);
            app.SprocketEditFieldLabel.Text = 'Sprocket:';
            app.SprocketEditField = uieditfield(app.ApplicationOverviewPanel, 'numeric');
            app.SprocketEditField.Editable = 'off';
            app.TsEditFieldLabel = uilabel(app.ApplicationOverviewPanel);
            app.TsEditFieldLabel.Text = 'Ts';
            app.TsEditField = uieditfield(app.ApplicationOverviewPanel, 'numeric');
            app.TsEditField.ValueDisplayFormat = '%11.5g';
            app.TsEditField.Editable = 'off';
            app.ModeLabel = uilabel(app.ApplicationOverviewPanel);
            app.ModeLabel.Text = 'Mode:';
            app.ModeEditField = uieditfield(app.ApplicationOverviewPanel, 'text');
            app.AppCtrlPanel = uipanel(app.LUPAUIFigure);
            app.AppCtrlPanel.Title = 'App Ctrl';
            app.StartStopButton = uibutton(app.AppCtrlPanel, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'StartStopButton';
            app.EnableDriveButton = uibutton(app.AppCtrlPanel, 'push');
            app.EnableDriveButton.ButtonPushedFcn = createCallbackFcn(app, @EnableDriveButtonPushed, true);
            app.EnableDriveButton.Text = 'Enable Drive';
            app.ClearFaultsButton = uibutton(app.AppCtrlPanel, 'push');
            app.ClearFaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFaultsButtonPushed, true);
            app.ClearFaultsButton.Text = 'Clear Faults';
            app.ProjectEditFieldLabel = uilabel(app.AppCtrlPanel);
            app.ProjectEditFieldLabel.Text = 'Project';
            app.ProjectEditField = uieditfield(app.AppCtrlPanel, 'text');
            app.ProjectEditField.Value = 'TEAMERMTULUPA5';
            app.ExperimentEditFieldLabel = uilabel(app.AppCtrlPanel);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.AppCtrlPanel, 'text');
            app.TrialSpinnerLabel = uilabel(app.AppCtrlPanel);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.AppCtrlPanel);
            app.TrialSpinner.Limits = [0 Inf];
            app.TrialSpinner.Value = 1;
            app.CurrentLimitSpinnerLabel = uilabel(app.AppCtrlPanel);
            app.CurrentLimitSpinnerLabel.Text = 'CurrentLimit';
            app.CurrentLimitSpinner = uispinner(app.AppCtrlPanel);
            app.CurrentLimitSpinner.Limits = [0 13];
            app.CurrentLimitSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentLimitSpinnerValueChanged, true);
            app.CurrentLimitSpinner.Value = 1;
            app.FaultLampLabel = uilabel(app.AppCtrlPanel);
            app.FaultLampLabel.Text = 'Fault';
            app.FaultLamp = uilamp(app.AppCtrlPanel);
            app.EStopLampLabel = uilabel(app.AppCtrlPanel);
            app.EStopLampLabel.Text = 'E-Stop';
            app.EStopLamp = uilamp(app.AppCtrlPanel);
            app.HWRLShareSwitchLabel = uilabel(app.AppCtrlPanel);
            app.HWRLShareSwitchLabel.Text = 'HWRL Share';
            app.HWRLShareSwitch = uiswitch(app.AppCtrlPanel, 'slider');
            app.HWRLShareSwitch.Items = {'No', 'Yes'};
            app.HWRLShareSwitch.ValueChangedFcn = createCallbackFcn(app, @HWRLShareSwitchValueChanged, true);
            app.HWRLShareSwitch.Value = 'Yes';
            app.ReferenceSignalPanel = uipanel(app.LUPAUIFigure);
            app.ReferenceSignalPanel.Title = 'Reference Signal';
            app.SignalDropDownLabel = uilabel(app.ReferenceSignalPanel);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.ReferenceSignalPanel);
            app.SignalDropDown.Items = {'None', 'Ramps', 'Sine', 'MS1', 'MS2', 'MS3', 'Chirp', 'BandLimitedWN'};
            app.SignalDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalDropDownValueChanged, true);
            app.SignalDropDown.Value = 'None';
            app.AmplitudeSpinnerLabel = uilabel(app.ReferenceSignalPanel);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.AmplitudeSpinner = uispinner(app.ReferenceSignalPanel);
            app.AmplitudeSpinner.Step = 0.1;
            app.AmplitudeSpinner.Limits = [0 Inf];
            app.AmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSpinnerValueChanged, true);
            app.SinePeriodEditFieldLabel = uilabel(app.ReferenceSignalPanel);
            app.SinePeriodEditFieldLabel.Text = 'SinePeriod';
            app.SinePeriodEditField = uieditfield(app.ReferenceSignalPanel, 'numeric');
            app.SinePeriodEditField.Editable = 'off';
            app.FeedbackControlPanel = uipanel(app.LUPAUIFigure);
            app.FeedbackControlPanel.Title = 'Feedback Control';
            app.SourceDropDownLabel = uilabel(app.FeedbackControlPanel);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.FeedbackControlPanel);
            app.SourceDropDown.Items = {'Manual', 'Excel'};
            app.SourceDropDown.ValueChangedFcn = createCallbackFcn(app, @SourceDropDownValueChanged, true);
            app.SourceDropDown.Value = 'Manual';
            app.DampingSpinnerLabel = uilabel(app.FeedbackControlPanel);
            app.DampingSpinnerLabel.Text = 'Damping';
            app.DampingSpinner = uispinner(app.FeedbackControlPanel);
            app.DampingSpinner.Step = 100;
            app.DampingSpinner.Limits = [0 10000];
            app.DampingSpinner.ValueChangedFcn = createCallbackFcn(app, @DampingSpinnerValueChanged, true);
            app.StiffnessSpinnerLabel = uilabel(app.FeedbackControlPanel);
            app.StiffnessSpinnerLabel.Text = 'Stiffness';
            app.StiffnessSpinner = uispinner(app.FeedbackControlPanel);
            app.StiffnessSpinner.Limits = [-10000 10000];
            app.StiffnessSpinner.ValueChangedFcn = createCallbackFcn(app, @StiffnessSpinnerValueChanged, true);
            app.EnableFeedbackControlButton = uibutton(app.FeedbackControlPanel, 'push');
            app.EnableFeedbackControlButton.ButtonPushedFcn = createCallbackFcn(app, @EnableFeedbackControlButtonPushed, true);
            app.EnableFeedbackControlButton.Text = 'Enable Feedback Control';
            app.MotorFeedbackPanel = uipanel(app.LUPAUIFigure);
            app.MotorFeedbackPanel.Title = 'Motor Feedback';
            app.Current = uiaxes(app.MotorFeedbackPanel);
            title(app.Current, 'Motor Current')
            xlabel(app.Current, 'time(s)')
            ylabel(app.Current, 'Current(A)')
            zlabel(app.Current, 'Z')
            app.MotorPowerWGaugeLabel = uilabel(app.MotorFeedbackPanel);
            app.MotorPowerWGaugeLabel.Text = 'Motor Power (W)';
            app.MotorPowerWGauge = uigauge(app.MotorFeedbackPanel, 'linear');
            app.MotorPowerWGauge.Limits = [-100 10];
            app.MotorPositionradGaugeLabel = uilabel(app.MotorFeedbackPanel);
            app.MotorPositionradGaugeLabel.Text = 'Motor Position (rad)';
            app.MotorPositionradGauge = uigauge(app.MotorFeedbackPanel, 'circular');
            app.MotorPositionradGauge.Limits = [-6.28318530717959 6.28318530717959];
            app.CountsEditFieldLabel = uilabel(app.MotorFeedbackPanel);
            app.CountsEditFieldLabel.Text = 'Counts';
            app.CountsEditField = uieditfield(app.MotorFeedbackPanel, 'numeric');
            app.LinearMotionFeedbackPanel = uipanel(app.LUPAUIFigure);
            app.LinearMotionFeedbackPanel.Title = 'Linear Motion Feedback';
            app.RelativePositionLabel = uilabel(app.LinearMotionFeedbackPanel);
            app.RelativePositionLabel.Text = 'Relative Body Position (m)';
            app.RelativeBodyPositionmGauge = uigauge(app.LinearMotionFeedbackPanel, 'linear');
            app.RelativeBodyPositionmGauge.Limits = [-0.3 0.3];
            app.NetForceNGaugeLabel = uilabel(app.LinearMotionFeedbackPanel);
            app.NetForceNGaugeLabel.Text = 'Net Force (N)';
            app.NetForceNGauge = uigauge(app.LinearMotionFeedbackPanel, 'linear');
            app.NetForceNGauge.Limits = [-400 400];
            app.ScopesPanel = uipanel(app.LUPAUIFigure);
            app.ScopesPanel.Title = 'Scopes';
            app.Velocity = uiaxes(app.ScopesPanel);
            title(app.Velocity, 'Motor Velocity')
            ylabel(app.Velocity, 'Velocity (rad/s)')
            zlabel(app.Velocity, 'Z')
            app.Torque = uiaxes(app.ScopesPanel);
            title(app.Torque, 'Motor Torque')
            ylabel(app.Torque, 'Torque (Nm)')
            zlabel(app.Torque, 'Z')
            app.Power = uiaxes(app.ScopesPanel);
            title(app.Power, 'Power')
            xlabel(app.Power, 'time (s)')
            ylabel(app.Power, 'Power (W)')
            zlabel(app.Power, 'Z')
            app.LoadCell = uiaxes(app.ScopesPanel);
            ylabel(app.LoadCell, 'LC F (N)')
            zlabel(app.LoadCell, 'Z')
            app.position = uiaxes(app.ScopesPanel);
            title(app.position, 'Motor Position')
            xlabel(app.position, 'time (s)')
            ylabel(app.position, 'rad')
            zlabel(app.position, 'Z')
            app.Stiffness = uiaxes(app.ScopesPanel);
            title(app.Stiffness, 'Stiffness')
            ylabel(app.Stiffness, 'Nm/rad')
            app.Damping = uiaxes(app.ScopesPanel);
            title(app.Damping, 'Damping')
            ylabel(app.Damping, 'Nm/rad/s')
            app.CustomFeedbackControlPanel = uipanel(app.LUPAUIFigure);
            app.CustomFeedbackControlPanel.Title = 'Custom Feedback Control';
            app.AgentDropDownLabel = uilabel(app.CustomFeedbackControlPanel);
            app.AgentDropDownLabel.Text = 'Agent';
            app.AgentDropDown = uidropdown(app.CustomFeedbackControlPanel);
            app.AgentDropDown.ValueChangedFcn = createCallbackFcn(app, @AgentDropDownValueChanged, true);
            app.EnableCustomFeedbackControlButton = uibutton(app.CustomFeedbackControlPanel, 'push');
            app.EnableCustomFeedbackControlButton.ButtonPushedFcn = createCallbackFcn(app, @EnableCustomFeedbackControlButtonPushed, true);
            app.EnableCustomFeedbackControlButton.Text = 'Enable Custom Feedback Control';
            % Show the figure after all components are created
            app.LUPAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LUPAapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LUPAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LUPAUIFigure)
        end
    end
end